classdef app_fast_design_PM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GeometricalandwindingspecificationsPanel  matlab.ui.container.Panel
        MachineaspectratioEditFieldLabel  matlab.ui.control.Label
        lambda                          matlab.ui.control.NumericEditField
        PolesnumberLabel                matlab.ui.control.Label
        p                               matlab.ui.control.NumericEditField
        ofslotsperpoleperphaseEditFieldLabel  matlab.ui.control.Label
        q                               matlab.ui.control.NumericEditField
        MinimumdiameterDagmLabel        matlab.ui.control.Label
        Dag                             matlab.ui.control.NumericEditField
        AirgapthicknesshagmmEditFieldLabel  matlab.ui.control.Label
        hag                             matlab.ui.control.NumericEditField
        MagnetradialthicknesslmmmLabel  matlab.ui.control.Label
        lm                              matlab.ui.control.NumericEditField
        MagnetwidthasofpolepitchEditFieldLabel  matlab.ui.control.Label
        alfa_m                          matlab.ui.control.NumericEditField
        WindingpitchreductioninslotnrEditFieldLabel  matlab.ui.control.Label
        nr                              matlab.ui.control.NumericEditField
        SlotfillingcoefficientkfillEditFieldLabel  matlab.ui.control.Label
        kfill                           matlab.ui.control.NumericEditField
        MachineaspectratioGaugeLabel    matlab.ui.control.Label
        lambda_level                    matlab.ui.control.SemicircularGauge
        windingfactorkwdgLabel          matlab.ui.control.Label
        kwdg                            matlab.ui.control.NumericEditField
        CenterPanel                     matlab.ui.container.Panel
        PermessiblematerialstressloadingsPanel  matlab.ui.container.Panel
        MaxteethBTEditFieldLabel        matlab.ui.control.Label
        Bt_max                          matlab.ui.control.NumericEditField
        MaxstatoryokeTEditFieldLabel    matlab.ui.control.Label
        Bys_max                         matlab.ui.control.NumericEditField
        MaxrotoryokeTEditFieldLabel     matlab.ui.control.Label
        Byr_max                         matlab.ui.control.NumericEditField
        RmscurrentdensityAmm2EditFieldLabel  matlab.ui.control.Label
        Jmax                            matlab.ui.control.NumericEditField
        Bt_max_level                    matlab.ui.control.LinearGauge
        Bys_max_level                   matlab.ui.control.LinearGauge
        Byr_max_level                   matlab.ui.control.LinearGauge
        Jmax_level                      matlab.ui.control.LinearGauge
        typeofslotsSwitchLabel          matlab.ui.control.Label
        typeofslotsSwitch               matlab.ui.control.Switch
        BtBagGaugeLabel                 matlab.ui.control.Label
        BtBag_level                     matlab.ui.control.SemicircularGauge
        ByrBagLabel                     matlab.ui.control.Label
        ByBag_level                     matlab.ui.control.SemicircularGauge
        BagGaugeLabel                   matlab.ui.control.Label
        Bag_level                       matlab.ui.control.SemicircularGauge
        Button                          matlab.ui.control.Button
        KsJA2mm2cmLabel                 matlab.ui.control.Label
        KsJ                             matlab.ui.control.LinearGauge
        RightPanel                      matlab.ui.container.Panel
        RateddataPanel                  matlab.ui.container.Panel
        TorqueTrNmEditFieldLabel        matlab.ui.control.Label
        Tr                              matlab.ui.control.NumericEditField
        SpeednrpmEditFieldLabel         matlab.ui.control.Label
        n                               matlab.ui.control.NumericEditField
        PhvoltageVrVEditFieldLabel      matlab.ui.control.Label
        Vr                              matlab.ui.control.NumericEditField
        OperatingtempCEditFieldLabel    matlab.ui.control.Label
        T_op                            matlab.ui.control.NumericEditField
        MaterialspecificationsPanel     matlab.ui.container.Panel
        MagremanenceTEditFieldLabel     matlab.ui.control.Label
        Br                              matlab.ui.control.NumericEditField
        RelativepermeabEditFieldLabel   matlab.ui.control.Label
        mu_r                            matlab.ui.control.NumericEditField
        LampackcoeffEditFieldLabel      matlab.ui.control.Label
        LampackcoeffEditField           matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Bt_max
        function Bt_maxValueChanged(app, event)

            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)

%% design of PM
%permissible material stress loadings
%Bts_max maximum value induction teeth stator
Bt_max=app.Bt_max.Value;%[T]
%Bys_max maximum value induction yoke stator
Bys_max=app.Bys_max.Value;%[T]
%Byr_max maximum value induction yoke rotor
Byr_max=app.Byr_max.Value;%[T]
%Jmax Conductors RMS current density
Jmax=app.Jmax.Value;%[A/mm^2](range of applications -->
...0.5/15 [A/mm^2] stator either rotor)
%%p double pole%%


%Bg1 density of flux in the air gap 1 harmonic peak value
%Dag diameter till air gap [m]
%Lcore length of the PM %[m]
Dag=app.Dag.Value;%[m]
%Machine aspect ratio lambda=Lcore/Dag;
lambda=app.lambda.Value;%(range of applications --> 0.5/3)
app.lambda_level.Value=lambda;
Lcore=lambda*app.Dag.Value;


%% magnet data
%mu_r relative permeability
%mu_0 absolute permeability
mu_0=pi*4e-7;%[H/m]
mu_r=app.mu_r.Value;% magnet mu
lm=app.lm.Value;%[mm] %length of magnets
carter=0.8; %Carter coefficent
%air gap
hag=app.hag.Value;%[mm]

%Br residual induction[T]
Br=app.Br.Value;
%hag_ equivalent air gap
hag_=hag*carter;% carter can be computated by a single function
%lm_ equivalent length of magnet= lm/mu
lm_=lm/mu_r; %[mm]
%Bg induction all harmonics
Bg=Br*lm_/(lm_+hag_)
app.Bag_level.Value=Bg;
%alfa_m the opening angle of magnets
alfa_m=app.alfa_m.Value;%[rad]
%Bg1 fondamental harmonic of Bg
Bg1=4/pi*Bg*sin(alfa_m*pi/2);

%% known the torque we compute the Ks
%Tr=@(Bg1, Ks, Dag, Lcore) sqrt(2)*pi/4*Bg1*Ks*Dag.^2*Lcore;
Tr=app.Tr.Value;%[Nm]

Ks=Tr/(sqrt(2)*pi/4*Bg1*Dag.^2*Lcore);
%% 
prop=20; %coefficient of geometry
%p number of poles
%Dis internal stator
delta_is=Dag*1/prop;%[m]
Dis=Dag+delta_is;%[m]
p=app.p.Value;
%tau_p polar pitch [m]
tau_p=pi*Dis/p
%wm magnet length [m]
wm=alfa_m*tau_p;
%flux per pole
phi_p=Bg*wm*Lcore;
phi_p1=2/pi*Bg1*tau_p*Lcore;
%Byr induction yoke rotor
%Der diameter external rotor
%Dir Diameter internal rotor
%%
%Ki???????
%%
%delta_xy length between the surface [m]
%D_ab internal/external rotor/stator [m]
delta_er=Dag*1/prop%[m]
Der=Dag-delta_er;%[m]
delta_ir=Der*1/prop;%[m] 
Dir=Der-13*delta_ir;%[m]
%
Ki=0.90;%???????
%
Byr=phi_p/2/( (Der-Dir)/2*Lcore*Ki);%[T]

%flux yoke stator
phi_ys=phi_p/2;

%
hys=Bg/Bys_max/Ki*alfa_m*tau_p/2;%[m]


q=app.q.Value;
%Ns number of slot stator
Ns=3*q*p;
%the effect of leakage flux  evident when
...magnet coverage is large then slots
    %stator solt pitch
tau_s=pi*Dis/Ns%[m]
%if q==1
    %wtt length teeth top
   wtt=Bg/Bt_max/Ki*tau_s;
%else
    %wtt=Bg/Bt_max/Ki*(alfa_m*tau_p-(tau_s-tau_p)/2);
%end


delta_h1=1/prop*Dis;%[m]
Dh1=Dis+delta_h1;%[m]
h1=Dh1-Dis;
wst=pi*Dh1/Ns-wtt;%[m]


%% rectangual or trapezoidal slots
%flag_slot=1;% 1 to set rectangualr slots 0 trapezoidal
 
if app.typeofslotsSwitch.Value %flag_slot
    %kfill slot filling coefficient
%kwdg winding factor
kfill=app.kfill.Value;
kwdg=app.kwdg.Value;
%Aus useful single slot area 
Aus=Ks*pi*Dh1/(Jmax*1e6*Ns*kwdg*kfill)
 % hus useful height x rectangular slots
 hus=Aus/wst;
 wsb=wst;
 theta=2*asin(wtt/2*Dh1/2);
else
    %
kfill=app.kfill.Value;
kwdg=app.kwdg.Value;
%Aus useful single slot area 
Aus=Ks*pi*Dh1/(Jmax*1e6*Ns*kwdg*kfill)
 % hus useful height x rectangular slots
 hus=Aus/wst;
    %
    wtb=wtt
     wsb=(pi*(Dh1+2*hus)/Ns)-wtb
    Aus=(wst+wsb/2)*hus
    
    hus=(-wst+sqrt(wst.^2+(4*pi*Aus/Ns)))/(2*pi/Ns)
     theta=2*asin(wtt/2/(Dh1/2));

end

hyr=phi_p/2/(Byr_max*Ki*Lcore);
Des=Dh1+hus+hys;
nr=app.nr.Value;
m=3;
if nr~=1
    layer=2;
else
    layer=1;
end
Iabc=[1;-2;1];%colomn
Tr=fast_plot_PM_FEMM(Lcore,Dir, Der, Dis, Des, Dh1, h1, hus, wst, wsb, theta,Ns,wm,lm,p,layer, nr, m, q, Iabc,alfa_mag);
%
app.Bt_max_level.Value=app.Bt_max.Value
app.Bys_max_level.Value=app.Bys_max.Value
app.Byr_max_level.Value=app.Byr_max.Value
%
%Ks
app.KsJ.Value=Ks/100*Jmax;%[A^2/mm^2/cm]
app.BtBag_level.Value=Bt_max/Bg;
app.ByBag_level.Value=Byr/Bg;
app.Jmax_level.Value=app.Jmax.Value;

        end

        % Value changed function: Bys_max
        function Bys_maxValueChanged(app, event)
            app.Bys_max_level.Value = app.Bys_max.Value;
            
        end

        % Value changed function: Byr_max
        function Byr_maxValueChanged(app, event)
            app.Byr_max_level.Value = app.Byr_max.Value;
            
        end

        % Value changed function: Jmax
        function JmaxValueChanged(app, event)
            app.Jmax_level.Value = app.Jmax.Value;
            
        end

        % Value changed function: typeofslotsSwitch
        function typeofslotsSwitchValueChanged(app, event)
            value = app.typeofslotsSwitch.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GeometricalandwindingspecificationsPanel = uipanel(app.LeftPanel);
            app.GeometricalandwindingspecificationsPanel.Title = 'Geometrical and winding specifications';
            app.MachineaspectratioEditFieldLabel = uilabel(app.GeometricalandwindingspecificationsPanel);
            app.MachineaspectratioEditFieldLabel.Text = 'Machine aspect ratio';
            app.lambda = uieditfield(app.GeometricalandwindingspecificationsPanel, 'numeric');
            app.lambda.Value = 0.5;
            app.PolesnumberLabel = uilabel(app.GeometricalandwindingspecificationsPanel);
            app.PolesnumberLabel.Text = 'Poles number';
            app.p = uieditfield(app.GeometricalandwindingspecificationsPanel, 'numeric');
            app.p.Value = 8;
            app.ofslotsperpoleperphaseEditFieldLabel = uilabel(app.GeometricalandwindingspecificationsPanel);
            app.ofslotsperpoleperphaseEditFieldLabel.Text = '# of slots per pole per phase ';
            app.q = uieditfield(app.GeometricalandwindingspecificationsPanel, 'numeric');
            app.q.Value = 1;
            app.MinimumdiameterDagmLabel = uilabel(app.GeometricalandwindingspecificationsPanel);
            app.MinimumdiameterDagmLabel.Text = 'Minimum diameter Dag [m]';
            app.Dag = uieditfield(app.GeometricalandwindingspecificationsPanel, 'numeric');
            app.Dag.Value = 0.3;
            app.AirgapthicknesshagmmEditFieldLabel = uilabel(app.GeometricalandwindingspecificationsPanel);
            app.AirgapthicknesshagmmEditFieldLabel.Text = 'Air gap thickness hag [mm]';
            app.hag = uieditfield(app.GeometricalandwindingspecificationsPanel, 'numeric');
            app.hag.Value = 3;
            app.MagnetradialthicknesslmmmLabel = uilabel(app.GeometricalandwindingspecificationsPanel);
            app.MagnetradialthicknesslmmmLabel.Text = 'Magnet radial thickness lm [mm]';
            app.lm = uieditfield(app.GeometricalandwindingspecificationsPanel, 'numeric');
            app.lm.Value = 7;
            app.MagnetwidthasofpolepitchEditFieldLabel = uilabel(app.GeometricalandwindingspecificationsPanel);
            app.MagnetwidthasofpolepitchEditFieldLabel.Text = 'Magnet width as % of pole pitch';
            app.alfa_m = uieditfield(app.GeometricalandwindingspecificationsPanel, 'numeric');
            app.alfa_m.Value = 0.9;
            app.WindingpitchreductioninslotnrEditFieldLabel = uilabel(app.GeometricalandwindingspecificationsPanel);
            app.WindingpitchreductioninslotnrEditFieldLabel.Text = 'Winding pitch reduction in slot # nr';
            app.nr = uieditfield(app.GeometricalandwindingspecificationsPanel, 'numeric');
            app.SlotfillingcoefficientkfillEditFieldLabel = uilabel(app.GeometricalandwindingspecificationsPanel);
            app.SlotfillingcoefficientkfillEditFieldLabel.Text = 'Slot  fillingcoefficient kfill';
            app.kfill = uieditfield(app.GeometricalandwindingspecificationsPanel, 'numeric');
            app.kfill.Value = 0.5;
            app.MachineaspectratioGaugeLabel = uilabel(app.GeometricalandwindingspecificationsPanel);
            app.MachineaspectratioGaugeLabel.Text = 'Machine aspect ratio';
            app.lambda_level = uigauge(app.GeometricalandwindingspecificationsPanel, 'semicircular');
            app.lambda_level.Limits = [0.5 3];
            app.windingfactorkwdgLabel = uilabel(app.GeometricalandwindingspecificationsPanel);
            app.windingfactorkwdgLabel.Text = 'winding factor kwdg';
            app.kwdg = uieditfield(app.GeometricalandwindingspecificationsPanel, 'numeric');
            app.kwdg.Value = 0.75;
            app.CenterPanel = uipanel(app.GridLayout);
            app.PermessiblematerialstressloadingsPanel = uipanel(app.CenterPanel);
            app.PermessiblematerialstressloadingsPanel.Title = 'Permessible material stress loadings';
            app.MaxteethBTEditFieldLabel = uilabel(app.PermessiblematerialstressloadingsPanel);
            app.MaxteethBTEditFieldLabel.Text = 'Max teeth B[T]';
            app.Bt_max = uieditfield(app.PermessiblematerialstressloadingsPanel, 'numeric');
            app.Bt_max.ValueChangedFcn = createCallbackFcn(app, @Bt_maxValueChanged, true);
            app.Bt_max.Value = 1.5;
            app.MaxstatoryokeTEditFieldLabel = uilabel(app.PermessiblematerialstressloadingsPanel);
            app.MaxstatoryokeTEditFieldLabel.Text = 'Max stator yoke[T]';
            app.Bys_max = uieditfield(app.PermessiblematerialstressloadingsPanel, 'numeric');
            app.Bys_max.ValueChangedFcn = createCallbackFcn(app, @Bys_maxValueChanged, true);
            app.Bys_max.Value = 1.3;
            app.MaxrotoryokeTEditFieldLabel = uilabel(app.PermessiblematerialstressloadingsPanel);
            app.MaxrotoryokeTEditFieldLabel.Text = 'Max rotor yoke[T]';
            app.Byr_max = uieditfield(app.PermessiblematerialstressloadingsPanel, 'numeric');
            app.Byr_max.ValueChangedFcn = createCallbackFcn(app, @Byr_maxValueChanged, true);
            app.Byr_max.Value = 1.3;
            app.RmscurrentdensityAmm2EditFieldLabel = uilabel(app.PermessiblematerialstressloadingsPanel);
            app.RmscurrentdensityAmm2EditFieldLabel.Text = 'Rms current density[A/mm^2]';
            app.Jmax = uieditfield(app.PermessiblematerialstressloadingsPanel, 'numeric');
            app.Jmax.ValueChangedFcn = createCallbackFcn(app, @JmaxValueChanged, true);
            app.Jmax.Value = 6;
            app.Bt_max_level = uigauge(app.PermessiblematerialstressloadingsPanel, 'linear');
            app.Bt_max_level.Limits = [0.8 2];
            app.Bys_max_level = uigauge(app.PermessiblematerialstressloadingsPanel, 'linear');
            app.Bys_max_level.Limits = [0.8 2];
            app.Byr_max_level = uigauge(app.PermessiblematerialstressloadingsPanel, 'linear');
            app.Byr_max_level.Limits = [0.8 2];
            app.Jmax_level = uigauge(app.PermessiblematerialstressloadingsPanel, 'linear');
            app.Jmax_level.Limits = [0.5 15];
            app.typeofslotsSwitchLabel = uilabel(app.CenterPanel);
            app.typeofslotsSwitchLabel.Text = 'type of slots';
            app.typeofslotsSwitch = uiswitch(app.CenterPanel, 'slider');
            app.typeofslotsSwitch.Items = {'Trapezoidal', 'Rectangualr'};
            app.typeofslotsSwitch.ValueChangedFcn = createCallbackFcn(app, @typeofslotsSwitchValueChanged, true);
            app.typeofslotsSwitch.Value = 'Rectangualr';
            app.BtBagGaugeLabel = uilabel(app.CenterPanel);
            app.BtBagGaugeLabel.Text = 'Bt/Bag';
            app.BtBag_level = uigauge(app.CenterPanel, 'semicircular');
            app.BtBag_level.Limits = [0 3];
            app.ByrBagLabel = uilabel(app.CenterPanel);
            app.ByrBagLabel.Text = 'Byr/Bag';
            app.ByBag_level = uigauge(app.CenterPanel, 'semicircular');
            app.ByBag_level.Limits = [0 3];
            app.BagGaugeLabel = uilabel(app.CenterPanel);
            app.BagGaugeLabel.Text = 'Bag';
            app.Bag_level = uigauge(app.CenterPanel, 'semicircular');
            app.Bag_level.Limits = [0.2 1.5];
            app.Button = uibutton(app.CenterPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.KsJA2mm2cmLabel = uilabel(app.CenterPanel);
            app.KsJA2mm2cmLabel.Text = 'KsJ [A^2/mm^2/cm]';
            app.KsJ = uigauge(app.CenterPanel, 'linear');
            app.KsJ.Limits = [1000 4000];
            app.RightPanel = uipanel(app.GridLayout);
            app.RateddataPanel = uipanel(app.RightPanel);
            app.RateddataPanel.Title = 'Rated data';
            app.TorqueTrNmEditFieldLabel = uilabel(app.RateddataPanel);
            app.TorqueTrNmEditFieldLabel.Text = 'Torque Tr [N/m]';
            app.Tr = uieditfield(app.RateddataPanel, 'numeric');
            app.Tr.Value = 35;
            app.SpeednrpmEditFieldLabel = uilabel(app.RateddataPanel);
            app.SpeednrpmEditFieldLabel.Text = 'Speed n [rpm]';
            app.n = uieditfield(app.RateddataPanel, 'numeric');
            app.PhvoltageVrVEditFieldLabel = uilabel(app.RateddataPanel);
            app.PhvoltageVrVEditFieldLabel.Text = 'Ph. voltage Vr [V]';
            app.Vr = uieditfield(app.RateddataPanel, 'numeric');
            app.OperatingtempCEditFieldLabel = uilabel(app.RateddataPanel);
            app.OperatingtempCEditFieldLabel.Text = 'Operating temp. [°C]';
            app.T_op = uieditfield(app.RateddataPanel, 'numeric');
            app.MaterialspecificationsPanel = uipanel(app.RightPanel);
            app.MaterialspecificationsPanel.Title = 'Material specifications';
            app.MagremanenceTEditFieldLabel = uilabel(app.MaterialspecificationsPanel);
            app.MagremanenceTEditFieldLabel.Text = 'Mag. remanence[T]';
            app.Br = uieditfield(app.MaterialspecificationsPanel, 'numeric');
            app.Br.Value = 0.9;
            app.RelativepermeabEditFieldLabel = uilabel(app.MaterialspecificationsPanel);
            app.RelativepermeabEditFieldLabel.Text = 'Relative permeab.';
            app.mu_r = uieditfield(app.MaterialspecificationsPanel, 'numeric');
            app.mu_r.Value = 1.5;
            app.LampackcoeffEditFieldLabel = uilabel(app.MaterialspecificationsPanel);
            app.LampackcoeffEditFieldLabel.Text = 'Lam. pack coeff.';
            app.LampackcoeffEditField = uieditfield(app.MaterialspecificationsPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_fast_design_PM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end